classdef App_test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        TabGroup            matlab.ui.container.TabGroup
        Main                matlab.ui.container.Tab
        nowMapName          matlab.ui.control.Label
        ChooseMap           matlab.ui.control.Button
        Label_15            matlab.ui.control.Label
        Label_13            matlab.ui.control.Label
        Goal                matlab.ui.control.EditField
        EditField_3Label_2  matlab.ui.control.Label
        Start               matlab.ui.control.EditField
        Label_12            matlab.ui.control.Label
        Locationfun         matlab.ui.control.EditField
        EditField_3Label    matlab.ui.control.Label
        Run                 matlab.ui.control.Button
        Mode                matlab.ui.container.ButtonGroup
        Stamina             matlab.ui.control.RadioButton
        Distance            matlab.ui.control.RadioButton
        MapEditor           matlab.ui.container.Tab
        locationPanel       matlab.ui.container.Panel
        UITable             matlab.ui.control.Table
        Label_20            matlab.ui.control.Label
        Label_19            matlab.ui.control.Label
        Label_18            matlab.ui.control.Label
        Label_17            matlab.ui.control.Label
        Label_16            matlab.ui.control.Label
        LocationName        matlab.ui.control.EditField
        Label_8             matlab.ui.control.Label
        Yposition           matlab.ui.control.NumericEditField
        YLabel              matlab.ui.control.Label
        Xposition           matlab.ui.control.NumericEditField
        XLabel              matlab.ui.control.Label
        addLocation         matlab.ui.control.Button
        loadPanel           matlab.ui.container.Panel
        Load                matlab.ui.control.Button
        LoadPath            matlab.ui.control.EditField
        newPanel            matlab.ui.container.Panel
        Path                matlab.ui.control.Button
        SavePath            matlab.ui.control.EditField
        Filename            matlab.ui.control.EditField
        EditField_2Label_2  matlab.ui.control.Label
        col                 matlab.ui.control.NumericEditField
        EditField_2Label    matlab.ui.control.Label
        row                 matlab.ui.control.NumericEditField
        Label_9             matlab.ui.control.Label
        Edit                matlab.ui.control.Button
        EditMode            matlab.ui.container.ButtonGroup
        Change              matlab.ui.control.ToggleButton
        New                 matlab.ui.control.ToggleButton
        Tab                 matlab.ui.container.Tab
        TextArea            matlab.ui.control.TextArea
        UIAxes              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        locationfun;%坐标转换函数
        start=[];%起点坐标
        goal=[];%终点坐标
        mode='distance';%导航模式
        loadpath;%导航加载的地图路径
        loadname;%导航加载的地图名称
        map;%导航加载的地图
        newMapName;%新建地图的名称
        newMapPath;%新建地图的路径
        mouth;%鼠标坐标
        isEdit=false;%是否在编辑
        EditMapPath;%正在编辑的地图的路径
        temtext;%临时文字
        ms;%报错信息窗口
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %读取当前文件夹下的Map
            nowfile=[pwd filesep,'Map',filesep,'OUC_map.mat'];
            app.map=load(nowfile);
            %打开时就显示地图
            App_plot_grid(app.map.grid_map,app.map.location,app);
            %坐标转换函数的句柄转换
            app.locationfun=str2func(app.Locationfun.Value);
            %文件保存默认路径设置
            app.SavePath.Value=[pwd filesep,'Map'];
        end

        % Callback function
        function StartValueChanged(app, event)
            %起点输入
            app.start=OUCRoomPosition(app.Start.Value);      
        end

        % Callback function
        function GoalValueChanged(app, event)
            %终点输入
            app.goal=OUCRoomPosition(app.Goal.Value);  
        end

        % Selection changed function: Mode
        function ModeSelectionChanged(app, event)
            %编辑模式调整
            if app.Stamina.Value
                app.mode='stamina';
            else
                app.mode='distance';
            end   
        end

        % Button pushed function: Run
        function RunButtonPushed(app, event)
            %开始导航
            if isempty(app.start)||isempty(app.goal)
                app.ms=msgbox('地点输入错误！', '警告', 'error');
                return;

            else
                Runfun(app.map.grid_map,app.map.location,app.start,app.goal,app.mode,app);
            end
        end

        % Button down function: MapEditor
        function MapEditorButtonDown(app, event)
            %切到地图编辑器时清空坐标区
            cla(app.UIAxes);
            close all;
            app.locationPanel.Visible="off";
        end

        % Button down function: Main
        function MainButtonDown(app, event)
            %切换回导航时退出编辑模式，显示地图
            app.isEdit=false;
            App_plot_grid(app.map.grid_map,app.map.location,app);
        end

        % Selection changed function: EditMode
        function EditModeSelectionChanged(app, event)
            %编辑模式的面板切换
            if app.Change.Value
                app.newPanel.Visible="off";
                app.loadPanel.Visible="on";
            elseif app.New.Value
                app.newPanel.Visible="on";
                app.loadPanel.Visible="off";
            end
        end

        % Button pushed function: Edit
        function EditButtonPushed(app, event)
            %开始编辑
            if app.New.Value
                if isempty(app.Filename.Value)
                    app.ms=msgbox('文件名不能为空！', '警告', 'error');
                    return;
                end
                if app.row.Value==0||app.row.Value==1||app.col.Value==0||app.col.Value==1
                    app.ms=msgbox('地图大小设置错误！', '警告', 'error');
                    return;
                 end
            end
            app.isEdit=true;
            app.locationPanel.Visible="on";
            if app.Change.Value
                nowmap=load(app.EditMapPath);
                App_plot_grid(nowmap.grid_map,nowmap.location,app);
            elseif app.New.Value
                app.EditMapPath=CreateMap(app,app.row.Value,app.col.Value,app.SavePath.Value,app.Filename.Value);
            end
            app.UITable.Data=nowmap.location;
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            %坐标区鼠标点击
            if app.isEdit
                mouthPosition=app.UIAxes.CurrentPoint;
                app.mouth(1)=mouthPosition(1,1);
                app.mouth(2)=mouthPosition(1,2);
                EditMap(app,app.EditMapPath,app.mouth);
            end
        end

        % Button pushed function: addLocation
        function addLocationButtonPushed(app, event)
            %地点添加
            locationEdit(app,app.EditMapPath,app.Xposition.Value,app.Yposition.Value,app.LocationName.Value);
            delete(app.temtext);
            app.Xposition.Value=0;
            app.Yposition.Value=0;
            app.LocationName.Value='';
        end

        % Value changed function: LocationName
        function LocationNameValueChanged(app, event)
            %显示未确定的地点
            delete(app.temtext);
            app.temtext=text(app.UIAxes,app.Xposition.Value,app.Yposition.Value,app.LocationName.Value);
        end

        % Value changed function: Yposition
        function YpositionValueChanged(app, event)
            %显示未确定的地点
            delete(app.temtext);
            app.temtext=text(app.UIAxes,app.Xposition.Value,app.Yposition.Value,app.LocationName.Value);
        end

        % Value changed function: Xposition
        function XpositionValueChanged(app, event)
            %显示未确定的地点
            delete(app.temtext);
            app.temtext=text(app.UIAxes,app.Xposition.Value,app.Yposition.Value,app.LocationName.Value);
        end

        % Value changed function: Start
        function StartValueChanged2(app, event)
            num = str2num(app.Start.Value);
            %起点输入
            if ~isempty(app.Locationfun.Value)
                app.start=app.locationfun(num);
                if isnan(app.start)
                    app.ms=msgbox('地点输入错误！请重新输入', '警告', 'error');
                    app.Start.Value='';
                end
            else
                %将坐标区坐标转为矩阵的行列坐标
                app.start(1,1)=num(1,2);
                app.start(1,2)=num(1,1);
            end

        end

        % Value changed function: Goal
        function GoalValueChanged2(app, event)
            num = str2num(app.Goal.Value);
            %终点输入
            if ~isempty(app.Locationfun.Value)
                app.goal=app.locationfun(num);
                if isnan(app.goal)
                    app.ms=msgbox('地点输入错误！请重新输入', '警告', 'error');
                    app.Start.Value='';
                end
            else
                %将坐标区坐标转为矩阵的行列坐标
                app.goal(1,1)=num(1,2);
                app.goal(1,2)=num(1,1);
            end
        end

        % Value changed function: Locationfun
        function LocationfunValueChanged(app, event)
            if isempty(app.Locationfun.Value)
                app.locationfun='';
            else
                app.locationfun=str2func(app.Locationfun.Value);
            end
        end

        % Button down function: Tab
        function TabButtonDown(app, event)
            app.isEdit=false;
        end

        % Button pushed function: ChooseMap
        function ChooseMapButtonPushed(app, event)
            %地图选择
            [app.loadname,app.loadpath]=uigetfile;  
            wholepath=[app.loadpath,app.loadname];
            try
                app.map=load(wholepath);
            catch
                app.ms=msgbox('未找到该文件！', '警告', 'error');
            end
            app.nowMapName.Text=app.loadname;
            App_plot_grid(app.map.grid_map,app.map.location,app);
        end

        % Button pushed function: Path
        function PathButtonPushed(app, event)
            %保存路径选择
            app.SavePath.Value=uigetdir;
        end

        % Button pushed function: Load
        function LoadButtonPushed(app, event)
            %修改地图路径
            [name,path]=uigetfile;
            app.EditMapPath=[path,name];
            app.LoadPath.Value=name;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            nowmap=load(app.EditMapPath);
            nowmap.location=app.UITable.Data;
            app.UITable.Data=nowmap.location;
            App_plot_grid(nowmap.grid_map,nowmap.location,app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Main = uitab(app.TabGroup);
            app.Main.Title = '导航';
            app.Main.ButtonDownFcn = createCallbackFcn(app, @MainButtonDown, true);
            app.Mode = uibuttongroup(app.Main);
            app.Mode.SelectionChangedFcn = createCallbackFcn(app, @ModeSelectionChanged, true);
            app.Mode.Title = '导航模式';
            app.Distance = uiradiobutton(app.Mode);
            app.Distance.Text = '距离优先';
            app.Distance.Value = true;
            app.Stamina = uiradiobutton(app.Mode);
            app.Stamina.Text = '体力优先';
            app.Run = uibutton(app.Main, 'push');
            app.Run.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.Run.Text = '开始导航';
            app.EditField_3Label = uilabel(app.Main);
            app.EditField_3Label.Text = '坐标转换函数';
            app.Locationfun = uieditfield(app.Main, 'text');
            app.Locationfun.ValueChangedFcn = createCallbackFcn(app, @LocationfunValueChanged, true);
            app.Locationfun.Value = 'OUCRoomPosition';
            app.Label_12 = uilabel(app.Main);
            app.Label_12.Text = '起点';
            app.Start = uieditfield(app.Main, 'text');
            app.Start.ValueChangedFcn = createCallbackFcn(app, @StartValueChanged2, true);
            app.EditField_3Label_2 = uilabel(app.Main);
            app.EditField_3Label_2.Text = '终点';
            app.Goal = uieditfield(app.Main, 'text');
            app.Goal.ValueChangedFcn = createCallbackFcn(app, @GoalValueChanged2, true);
            app.Label_13 = uilabel(app.Main);
            app.Label_13.Text = '(未选择转换函数时，直接输入坐标)';
            app.Label_15 = uilabel(app.Main);
            app.Label_15.Text = '急！要迟到了！';
            app.ChooseMap = uibutton(app.Main, 'push');
            app.ChooseMap.ButtonPushedFcn = createCallbackFcn(app, @ChooseMapButtonPushed, true);
            app.ChooseMap.Text = '选择地图';
            app.nowMapName = uilabel(app.Main);
            app.nowMapName.Text = 'OUC_map.mat';
            app.MapEditor = uitab(app.TabGroup);
            app.MapEditor.Title = '地图编辑器';
            app.MapEditor.ButtonDownFcn = createCallbackFcn(app, @MapEditorButtonDown, true);
            app.EditMode = uibuttongroup(app.MapEditor);
            app.EditMode.SelectionChangedFcn = createCallbackFcn(app, @EditModeSelectionChanged, true);
            app.EditMode.Title = '编辑模式';
            app.New = uitogglebutton(app.EditMode);
            app.New.Text = '新建';
            app.New.Value = true;
            app.Change = uitogglebutton(app.EditMode);
            app.Change.Text = '读取';
            app.Edit = uibutton(app.MapEditor, 'push');
            app.Edit.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.Edit.Text = '开始编辑';
            app.newPanel = uipanel(app.MapEditor);
            app.Label_9 = uilabel(app.newPanel);
            app.Label_9.Text = '行';
            app.row = uieditfield(app.newPanel, 'numeric');
            app.EditField_2Label = uilabel(app.newPanel);
            app.EditField_2Label.Text = '列';
            app.col = uieditfield(app.newPanel, 'numeric');
            app.EditField_2Label_2 = uilabel(app.newPanel);
            app.EditField_2Label_2.Text = '文件名称';
            app.Filename = uieditfield(app.newPanel, 'text');
            app.SavePath = uieditfield(app.newPanel, 'text');
            app.SavePath.Editable = 'off';
            app.SavePath.Value = 'D:\最高机密\matlab\期末项目\APP版本\Map';
            app.Path = uibutton(app.newPanel, 'push');
            app.Path.ButtonPushedFcn = createCallbackFcn(app, @PathButtonPushed, true);
            app.Path.Text = '保存路径';
            app.loadPanel = uipanel(app.MapEditor);
            app.loadPanel.Visible = 'off';
            app.LoadPath = uieditfield(app.loadPanel, 'text');
            app.LoadPath.Editable = 'off';
            app.Load = uibutton(app.loadPanel, 'push');
            app.Load.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.Load.Text = '读取文件';
            app.locationPanel = uipanel(app.MapEditor);
            app.locationPanel.Visible = 'off';
            app.addLocation = uibutton(app.locationPanel, 'push');
            app.addLocation.ButtonPushedFcn = createCallbackFcn(app, @addLocationButtonPushed, true);
            app.addLocation.Text = '添加地点';
            app.XLabel = uilabel(app.locationPanel);
            app.XLabel.Text = 'X轴坐标';
            app.Xposition = uieditfield(app.locationPanel, 'numeric');
            app.Xposition.ValueChangedFcn = createCallbackFcn(app, @XpositionValueChanged, true);
            app.YLabel = uilabel(app.locationPanel);
            app.YLabel.Text = 'Y轴坐标';
            app.Yposition = uieditfield(app.locationPanel, 'numeric');
            app.Yposition.ValueChangedFcn = createCallbackFcn(app, @YpositionValueChanged, true);
            app.Label_8 = uilabel(app.locationPanel);
            app.Label_8.Text = '地点名称';
            app.LocationName = uieditfield(app.locationPanel, 'text');
            app.LocationName.ValueChangedFcn = createCallbackFcn(app, @LocationNameValueChanged, true);
            app.Label_16 = uilabel(app.locationPanel);
            app.Label_16.Text = '黑色：障碍';
            app.Label_17 = uilabel(app.locationPanel);
            app.Label_17.Text = '蓝色：斜坡';
            app.Label_18 = uilabel(app.locationPanel);
            app.Label_18.Text = '粉色：楼梯';
            app.Label_19 = uilabel(app.locationPanel);
            app.Label_19.Text = '点击格子编辑';
            app.Label_20 = uilabel(app.locationPanel);
            app.Label_20.Text = '点击添加地点后储存';
            app.UITable = uitable(app.locationPanel);
            app.UITable.ColumnName = {'X'; 'Y'; '内容'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '介绍';
            app.Tab.ButtonDownFcn = createCallbackFcn(app, @TabButtonDown, true);
            app.TextArea = uitextarea(app.Tab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'1.灵感来源：下午56和78节课之间10分钟的时间我要穿越整个教学区上课时，迷路了'; ''; '2.导航部分运用了A*路径规划算法'; ''; '3.学校地形错综复杂，二维地图难以展示全部路线，导航路线与实际会有出入'; ''; '4.自定义坐标转换函数需要自己编写'; ''; '5.地图文件有一个数组(grid_map)储存地图信息，一个元胞(location)储存地点信息'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end